classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        compnumDropDownLabel          matlab.ui.control.Label
        compnumDropDown               matlab.ui.control.DropDown
        bif_startDropDownLabel        matlab.ui.control.Label
        bifstartDropDown              matlab.ui.control.DropDown
        bifendDropDownLabel           matlab.ui.control.Label
        bifendDropDown                matlab.ui.control.DropDown
        clearstartCheckBox            matlab.ui.control.CheckBox
        clearendCheckBox              matlab.ui.control.CheckBox
        componantfrequencypeakSliderLabel  matlab.ui.control.Label
        componantfrequencypeakSlider  matlab.ui.control.Slider
        SCMCheckBox                   matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        saveButton                    matlab.ui.control.Button
        ClearLastRowButton            matlab.ui.control.Button
        SendTableButton               matlab.ui.control.Button
        notesuseacontinuouslineTextAreaLabel  matlab.ui.control.Label
        notesuseacontinuouslineTextArea  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if isempty(app.UITable.Data)
                app.UITable.Data = table();
            end
            T = table;
            T.comp_num = app.compnumDropDown.Value;
            T.bif_start = {app.bifstartDropDown.Value};   
            T.bif_end = {app.bifendDropDown.Value};
            T.freq = app.componantfrequencypeakSlider.Value;
            T.scm = app.SCMCheckBox.Value;
            T.clear_start = app.clearstartCheckBox.Value;
            T.clear_end = app.clearendCheckBox.Value;
            T.notes = app.notesuseacontinuouslineTextArea.Value;
            app.UITable.Data = vertcat(app.UITable.Data, T);
        end

        % Callback function: ClearLastRowButton, UITable
        function ClearTableButtonPushed(app, event)
            app.UITable.Data = app.UITable.Data(1:end-1,:);
            
        end

        % Button pushed function: SendTableButton
        function SendTableButtonPushed(app, event)
            assignin ('base','dataTable', app.UITable.Data);
            closereq();     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.compnumDropDownLabel = uilabel(app.UIFigure);
            app.compnumDropDownLabel.Text = 'comp num';
            app.compnumDropDown = uidropdown(app.UIFigure);
            app.compnumDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.compnumDropDown.Editable = 'on';
            app.compnumDropDown.Value = '1';
            app.bif_startDropDownLabel = uilabel(app.UIFigure);
            app.bif_startDropDownLabel.Text = 'bif_start ';
            app.bifstartDropDown = uidropdown(app.UIFigure);
            app.bifstartDropDown.Items = {'not_clear', 'SN/SubH', 'SNIC', 'SupH', ''};
            app.bifstartDropDown.Editable = 'on';
            app.bifstartDropDown.Value = 'not_clear';
            app.bifendDropDownLabel = uilabel(app.UIFigure);
            app.bifendDropDownLabel.Text = 'bif end ';
            app.bifendDropDown = uidropdown(app.UIFigure);
            app.bifendDropDown.Items = {'not_clear', 'SH/SNIC', 'SupH', 'FLC'};
            app.bifendDropDown.Editable = 'on';
            app.bifendDropDown.Value = 'not_clear';
            app.clearstartCheckBox = uicheckbox(app.UIFigure);
            app.clearstartCheckBox.Text = 'clear start';
            app.clearendCheckBox = uicheckbox(app.UIFigure);
            app.clearendCheckBox.Text = 'clear end';
            app.componantfrequencypeakSliderLabel = uilabel(app.UIFigure);
            app.componantfrequencypeakSliderLabel.Text = 'componant frequency peak';
            app.componantfrequencypeakSlider = uislider(app.UIFigure);
            app.componantfrequencypeakSlider.Limits = [0 40];
            app.SCMCheckBox = uicheckbox(app.UIFigure);
            app.SCMCheckBox.Text = 'SCM ?';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'comp_num'; 'bif_start'; 'bif_end'; 'freq'; 'scm'; 'clear_start'; 'clear_end'; 'notes'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.ClearLastRowButton = uibutton(app.UIFigure, 'push');
            app.ClearLastRowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearLastRowButton.Text = 'Clear Last Row';
            app.SendTableButton = uibutton(app.UIFigure, 'push');
            app.SendTableButton.ButtonPushedFcn = createCallbackFcn(app, @SendTableButtonPushed, true);
            app.SendTableButton.Text = {'Send Table'; ''};
            app.notesuseacontinuouslineTextAreaLabel = uilabel(app.UIFigure);
            app.notesuseacontinuouslineTextAreaLabel.Text = 'notes ? (use a continuous line)';
            app.notesuseacontinuouslineTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end